classdef Mosyn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Program                       matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        OpenProjectMenu               matlab.ui.container.Menu
        LoadNodeLocationsMenu         matlab.ui.container.Menu
        CalculateThresholdMenu        matlab.ui.container.Menu
        ConfigureMenu                 matlab.ui.container.Menu
        CalculateIndexesMenu          matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        NodeLocationPanel             matlab.ui.container.Panel
        NodeLocationEditField         matlab.ui.control.EditField
        IndexesPanel                  matlab.ui.container.Panel
        TSCLimitEditField             matlab.ui.control.NumericEditField
        TSCLimitEditFieldLabel        matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        NodesPanel                    matlab.ui.container.Panel
        NumofNodesEditField           matlab.ui.control.NumericEditField
        NumofNodesEditFieldLabel      matlab.ui.control.Label
        GenerateTVGButton             matlab.ui.control.Button
        BandPassFilterPanel           matlab.ui.container.Panel
        AllowBandPassCheckBox         matlab.ui.control.CheckBox
        FinalFrequencyEditField       matlab.ui.control.NumericEditField
        FinalValueEditFieldLabel      matlab.ui.control.Label
        InitialFrequencyEditField     matlab.ui.control.NumericEditField
        InitialValueEditFieldLabel    matlab.ui.control.Label
        FrequencyPanel                matlab.ui.container.Panel
        AcqRateEditField              matlab.ui.control.NumericEditField
        AcqRateEditFieldLabel         matlab.ui.control.Label
        AllowTimeColumnCheckBox       matlab.ui.control.CheckBox
        MotifSynchronizationPropertiesPanel  matlab.ui.container.Panel
        TaoMaxEditField               matlab.ui.control.NumericEditField
        TaoMaxEditFieldLabel          matlab.ui.control.Label
        LagWindowEditField            matlab.ui.control.NumericEditField
        LagWindowEditField_3Label     matlab.ui.control.Label
        MotifLagEditField             matlab.ui.control.NumericEditField
        MotifLagEditField_2Label      matlab.ui.control.Label
        TaoMinEditField               matlab.ui.control.NumericEditField
        TaoMinEditFieldLabel          matlab.ui.control.Label
        ThresholdEditField            matlab.ui.control.NumericEditField
        ThresholdEditField_2Label     matlab.ui.control.Label
        WindowLengthEditField         matlab.ui.control.NumericEditField
        WindowLengthEditField_2Label  matlab.ui.control.Label
        TimeandFrequencyPanel         matlab.ui.container.Panel
        RealFinalTimeCheckBox         matlab.ui.control.CheckBox
        FinalTimeEditField            matlab.ui.control.NumericEditField
        FinalTimeEditFieldLabel       matlab.ui.control.Label
        InitialTimeEditField          matlab.ui.control.NumericEditField
        InitialTimeEditFieldLabel     matlab.ui.control.Label
        FilesPanel                    matlab.ui.container.Panel
        ListBox                       matlab.ui.control.ListBox
        DirectoryPanel                matlab.ui.container.Panel
        Open                          matlab.ui.control.Button
        DirectoryEditField            matlab.ui.control.EditField
    end


    properties (Access = private)
        files 
        path
        location
        
    end

    methods (Access = private)
    
        function KeyPress_Test(app)
            fig = app.Program('KeyPressFcn',@Key_Down,'KeyReleaseFcn',@Key_Up);
            function Key_Down(src,event)
                disp('down');
            end
            function Key_Up(src,evnt)
                disp('up');
            end
        end

        function configureFields(app) 

            filename = strcat(app.path, app.files{1});

            [~,name,ext] = fileparts(filename);

             if (ext == ".mat")

                load (filename, 'initialTime', 'finalTime', 'realTime', 'initialFrequency', 'finalFrequency', 'hasFrequency', 'removeTimeColumn', 'acqRate', 'slidWindow', 'taoMin', 'taoMax', 'threshold', 'numNodes' )


                app.AllowTimeColumnCheckBox.Value = removeTimeColumn;
                app.AllowBandPassCheckBox.Enable = 'off';

                app.AcqRateEditField.Value = acqRate;
                app.AcqRateEditField.Enable = 'off';

                app.AllowBandPassCheckBox.Value = hasFrequency;
                app.AllowBandPassCheckBox.Enable = 'off';

                app.InitialFrequencyEditField.Value = initialFrequency;
                app.InitialFrequencyEditField.Enable = 'off';

                app.FinalFrequencyEditField.Value = finalFrequency;
                app.FinalFrequencyEditField.Enable = 'off';
                
                app.InitialTimeEditField.Value = initialTime;
                app.InitialTimeEditField.Enable = 'off';
                
                app.FinalTimeEditField.Value = finalTime;
                app.FinalTimeEditField.Enable = 'off';

                app.RealFinalTimeCheckBox.Value = realTime;
                app.RealFinalTimeCheckBox.Enable = 'off';

                app.NumofNodesEditField.Value = numNodes;
                
                app.WindowLengthEditField.Value = slidWindow;
                app.WindowLengthEditField.Enable = 'off';
                
                app.ThresholdEditField.Value = threshold;
                app.ThresholdEditField.Enable = 'off';
                
                app.TaoMinEditField.Value = taoMin;
                app.TaoMinEditField.Enable = 'off';
                
                app.TaoMaxEditField.Value = taoMax;
                app.TaoMaxEditField.Enable = 'off';
                

             else
                app.getSavedValues()
             end

        end

        function getSavedValues(app)

            app.AllowTimeColumnCheckBox.Enable = 'on';
            app.AcqRateEditField.Enable = 'on';
            app.AllowBandPassCheckBox.Enable = 'on';
            app.InitialFrequencyEditField.Enable = 'on';
            app.FinalFrequencyEditField.Enable = 'on';
            app.InitialTimeEditField.Enable = 'on';
            app.FinalTimeEditField.Enable = 'on';
            app.RealFinalTimeCheckBox.Enable = 'on';
            app.WindowLengthEditField.Enable = 'on';
            app.ThresholdEditField.Enable = 'on';
            app.TaoMinEditField.Enable = 'on';
            app.TaoMaxEditField.Enable = 'on';

            if (~isempty(app.files) && ~isstring(app.files))
                app.ListBox.Items = app.files;
                nodes = getNumNode(strcat(app.path, app.files{1}));
                app.NumofNodesEditField.Value = nodes;
            end

            if(~isempty(getenv('Motif_AcqRate')))
                app.AcqRateEditField.Value = str2num(getenv('Motif_AcqRate'));
            end
            
            if(~isempty(getenv('Motif_InitialTime')))
                app.InitialTimeEditField.Value = str2num(getenv('Motif_InitialTime'));
            end
            
            if(~isempty(getenv('Motif_FinalTime')))
                app.FinalTimeEditField.Value = str2num(getenv('Motif_FinalTime'));
            end
            
            if(~isempty(getenv('Motif_WindowLength')))
                app.WindowLengthEditField.Value = str2num(getenv('Motif_WindowLength'));
            end
            
            if(~isempty(getenv('Motif_Threshold')))
                app.ThresholdEditField.Value = str2num(getenv('Motif_Threshold'));
            end
            
            if(~isempty(getenv('Motif_TaoMin')))
                app.TaoMinEditField.Value = str2num(getenv('Motif_TaoMin'));
            end
            
            if(~isempty(getenv('Motif_TaoMax')))
                app.TaoMaxEditField.Value = str2num(getenv('Motif_TaoMax'));
            end
            
            if(~isempty(getenv('Motif_MotifLagValue')))
                app.MotifLagEditField.Value = str2num(getenv('Motif_MotifLagValue'));
            end
            
            if(~isempty(getenv('Motif_LagWindow')))
                app.LagWindowEditField.Value = str2num(getenv('Motif_LagWindow'));
            end
            
            if(~isempty(getenv('Motif_TSCLim')))
                app.TSCLimitEditField.Value = str2num(getenv('Motif_TSCLim'));
            end

        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            global globalFiles globalPath;

            if (isempty(globalPath) || isnumeric(globalPath))
                globalPath = "";
            end

            if (isempty(globalFiles) || isnumeric(globalFiles))
                globalFiles = "";
            end

            app.files = globalFiles;
            app.path = globalPath;
            app.DirectoryEditField.Value = app.path;
            app.ListBox.Items = app.files;
            addpath('functions\');

            if (~isempty(app.files))
                app.GenerateTVGButton.Enable = 'on';
            end
            
            idx = TVG.Name;
            idx(2,:) = {false};
            idx(2,1:3) = {true};
            app.UITable.Data = idx';
            
            dirData = dir('*.asc');
            dirIndex = [dirData.isdir];
            
            app.configureFields()
            
            
        end

        % Menu selected function: OpenProjectMenu
        function OpenProjectMenuSelected(app, event)
            [file,path,indx] = uigetfile('*.mat');
            app.Program.Visible = 'off';
            app.Program.Visible = 'on';
            if indx > 0
                mat = load(strcat(path,file));
                project = mat.project;
                UISync(project);
            end
  
        end

        % Value changed function: AllowTimeColumnCheckBox
        function AllowTimeColumnCheckBoxValueChanged(app, event)
            value = app.AllowTimeColumnCheckBox.Value;
            if (value == 1)
                app.AcqRateEditField.Enable = 'off';
            else
                app.AcqRateEditField.Enable = 'on';
            end
        end

        % Button pushed function: GenerateTVGButton
        function GenerateTVGButtonPushed(app, event)

            initialTime = app.InitialTimeEditField.Value;
            finalTime = app.FinalTimeEditField.Value;
            slidWindow = app.WindowLengthEditField.Value;
            taoMin  = app.TaoMinEditField.Value; 
            taoMax  = app.TaoMaxEditField.Value; 
            threshold = app.ThresholdEditField.Value;
            motifLag = app.LagWindowEditField.Value;
            lagWindow = app.LagWindowEditField.Value;
            realTime = app.RealFinalTimeCheckBox.Value;
            initialFrequency= app.InitialFrequencyEditField.Value;
            finalFrequency = app.FinalFrequencyEditField.Value;
            hasFrequency = app.AllowBandPassCheckBox.Value;
            timeColumn = app.AllowTimeColumnCheckBox.Value;
            AcqRate = app.AcqRateEditField.Value;
            tscLim = app.TSCLimitEditField.Value;
            
            setenv('Motif_AcqRate', num2str(AcqRate));
            setenv('Motif_InitialTime', num2str(initialTime));
            setenv('Motif_FinalTime', num2str(finalTime)); 
            setenv('Motif_WindowLength', num2str(slidWindow)); 
            setenv('Motif_TaoMin', num2str(taoMin)); 
            setenv('Motif_TaoMax', num2str(taoMax));
            setenv('Motif_Threshold', num2str(threshold)); 
            setenv('Motif_MotifLagValue', num2str(motifLag));
            setenv('Motif_LagWindow', num2str(lagWindow));
            setenv('Motif_TSCLim', num2str(tscLim));

            calculateIndexes = app.CalculateIndexesMenu.Checked == 'on';
            
            idx = cell2table(app.UITable.Data);
            idx = idx(idx{:,2} == 1,:);
            idx = table2array(idx(:,1));
            app.GenerateTVGButton.Enable = 'off';
            [project] = calcIndexes(app.path,app.files, app.location, idx, initialTime, finalTime, realTime, initialFrequency, finalFrequency, hasFrequency, timeColumn, AcqRate, slidWindow, taoMin, taoMax, threshold, tscLim, calculateIndexes);
            app.GenerateTVGButton.Enable = 'on';

            if (project ~= -1 && calculateIndexes)
                UISync(project);
            end
         

        end

        % Callback function
        function CancelButtonPushed(app, event)
            close(app.Program);
        end

        % Button pushed function: Open
        function OpenButtonPushed(app, event)
            global globalFiles globalPath

            [app.files, app.path] = uigetfile({'*.asc;*.csv', 'Data Files (*.asc;*.csv)'; '*.tvg.mat', 'TVG Files (tvg.mat)'}, 'Select one or more files',  'MultiSelect', 'on', globalPath);

            app.Program.Visible = 'off';
            app.Program.Visible = 'on';
            if (~iscell(app.files))
                if (app.files == 0)
                    return;
                end
               
                app.files = cellstr(app.files);
               
            end

            globalFiles = app.files;
            globalPath = app.path;
            
            app.configureFields()
         
            app.GenerateTVGButton.Enable = 'on';
            app.ListBox.Items = app.files;
            app.DirectoryEditField.Value = app.path;

        end

        % Value changed function: DirectoryEditField
        function DirectoryEditFieldValueChanged(app, event)
            value = app.DirectoryEditField.Value;
            app.path = value;
            dirData = dir([value '*.asc']);
            dirIndex = [dirData.isdir];
            app.files = {dirData(~dirIndex).name}';
            app.ListBox.Items = app.files;
            
            if (~isempty(app.files))
                app.GenerateTVGButton.Enable = 'on';
            end
        end

        % Value changed function: RealFinalTimeCheckBox
        function RealFinalTimeCheckBoxValueChanged(app, event)
            value = app.RealFinalTimeCheckBox.Value;
            if(value == 1)
                app.FinalTimeEditField.Enable = false;
            else
                app.FinalTimeEditField.Enable = true;
            end
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            display(strcat(app.path,'\', value));
            nodes = getNumNode(strcat(app.path,'\', value));
            app.NumofNodesEditField.Value = nodes;
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox({'MoSyn - Motif Synchronization and Complex Network Analysis 4.0';'Developed by Neuro Redes (UFBA)';'Author: Marcos Netto';'Mail: mpnetto88@gmail.com'});
        end

        % Menu selected function: LoadNodeLocationsMenu
        function LoadNodeLocationsMenuSelected(app, event)
            [file,path,indx] = uigetfile('*.txt');
            app.Program.Visible = 'off';
            app.Program.Visible = 'on';
            if indx > 0
                app.location = strcat(path,file);
                app.NodeLocationEditField.Value = app.location;
            end
        end

        % Value changed function: AllowBandPassCheckBox
        function AllowBandPassCheckBoxValueChanged(app, event)
            value = app.AllowBandPassCheckBox.Value;
            if (value == 1)
                app.InitialFrequencyEditField.Enable = 'on';
                app.FinalFrequencyEditField.Enable = 'on';
            else
                app.InitialFrequencyEditField.Enable = 'off';
                app.FinalFrequencyEditField.Enable = 'off';
            end
        end

        % Menu selected function: CalculateThresholdMenu
        function CalculateThresholdMenuSelected(app, event)
            slidWindow = app.WindowLengthEditField.Value;
            taoMin  = app.TaoMinEditField.Value; 
            taoMax  = app.TaoMaxEditField.Value; 
            motifLag = app.LagWindowEditField.Value;
            lagWindow = app.LagWindowEditField.Value;
            
            if(length(app.files) > 0)
                thresholdApp = CalcThreshold(app.path, app.files(1), slidWindow, lagWindow, taoMin,taoMax, motifLag);
                 waitfor(thresholdApp);
                 display(thresholdApp);
                %app.ThresholdEditField.Value = thresholdApp.threshold;
            else
                uialert(app.Program,'You have to load at least one file','No File');
            end
            
        end

        % Menu selected function: CalculateIndexesMenu
        function CalculateIndexesClicked(app, event)
            
            if app.CalculateIndexesMenu.Checked == 'on';
               app.CalculateIndexesMenu.Checked = 'off';
            else
                 app.CalculateIndexesMenu.Checked = 'on';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Program = uifigure('Visible', 'off');
            app.Program.Name = 'MoSyn - Motif Synchronization';
            app.FileMenu = uimenu(app.Program);
            app.FileMenu.Text = 'File';
            app.OpenProjectMenu = uimenu(app.FileMenu);
            app.OpenProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenProjectMenuSelected, true);
            app.OpenProjectMenu.Separator = 'on';
            app.OpenProjectMenu.Accelerator = 'p';
            app.OpenProjectMenu.Text = 'Open Project';
            app.LoadNodeLocationsMenu = uimenu(app.FileMenu);
            app.LoadNodeLocationsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadNodeLocationsMenuSelected, true);
            app.LoadNodeLocationsMenu.Separator = 'on';
            app.LoadNodeLocationsMenu.Accelerator = 'L';
            app.LoadNodeLocationsMenu.Text = 'Load Node Locations';
            app.CalculateThresholdMenu = uimenu(app.FileMenu);
            app.CalculateThresholdMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateThresholdMenuSelected, true);
            app.CalculateThresholdMenu.Accelerator = 'T';
            app.CalculateThresholdMenu.Text = 'Calculate Threshold';
            app.ConfigureMenu = uimenu(app.Program);
            app.ConfigureMenu.Text = 'Configure';
            app.CalculateIndexesMenu = uimenu(app.ConfigureMenu);
            app.CalculateIndexesMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateIndexesClicked, true);
            app.CalculateIndexesMenu.Checked = 'on';
            app.CalculateIndexesMenu.Text = 'Calculate Indexes';
            app.AboutMenu = uimenu(app.Program);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.DirectoryPanel = uipanel(app.Program);
            app.DirectoryPanel.Title = 'Directory';
            app.DirectoryEditField = uieditfield(app.DirectoryPanel, 'text');
            app.DirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @DirectoryEditFieldValueChanged, true);
            app.Open = uibutton(app.DirectoryPanel, 'push');
            app.Open.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.Open.Text = '...';
            app.FilesPanel = uipanel(app.Program);
            app.FilesPanel.Title = 'Files';
            app.ListBox = uilistbox(app.FilesPanel);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.TimeandFrequencyPanel = uipanel(app.Program);
            app.TimeandFrequencyPanel.Title = 'Time and Frequency';
            app.InitialTimeEditFieldLabel = uilabel(app.TimeandFrequencyPanel);
            app.InitialTimeEditFieldLabel.Text = 'Initial Time';
            app.InitialTimeEditField = uieditfield(app.TimeandFrequencyPanel, 'numeric');
            app.InitialTimeEditField.ValueDisplayFormat = '%.0f';
            app.FinalTimeEditFieldLabel = uilabel(app.TimeandFrequencyPanel);
            app.FinalTimeEditFieldLabel.Text = 'Final Time';
            app.FinalTimeEditField = uieditfield(app.TimeandFrequencyPanel, 'numeric');
            app.FinalTimeEditField.Limits = [0 Inf];
            app.FinalTimeEditField.ValueDisplayFormat = '%.0f';
            app.FinalTimeEditField.Value = 1000;
            app.RealFinalTimeCheckBox = uicheckbox(app.TimeandFrequencyPanel);
            app.RealFinalTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RealFinalTimeCheckBoxValueChanged, true);
            app.RealFinalTimeCheckBox.Text = 'Real Final Time';
            app.MotifSynchronizationPropertiesPanel = uipanel(app.Program);
            app.MotifSynchronizationPropertiesPanel.Title = 'Motif Synchronization Properties';
            app.WindowLengthEditField_2Label = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.WindowLengthEditField_2Label.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.WindowLengthEditField.Value = 20;
            app.ThresholdEditField_2Label = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.ThresholdEditField_2Label.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 1];
            app.ThresholdEditField.Value = 0.9;
            app.TaoMinEditFieldLabel = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.TaoMinEditFieldLabel.Text = 'Tao Min';
            app.TaoMinEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.TaoMinEditField.Limits = [0 Inf];
            app.MotifLagEditField_2Label = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.MotifLagEditField_2Label.Text = 'Motif Lag';
            app.MotifLagEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.MotifLagEditField.Limits = [0 Inf];
            app.MotifLagEditField.Value = 1;
            app.LagWindowEditField_3Label = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.LagWindowEditField_3Label.Text = 'Lag Window';
            app.LagWindowEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.LagWindowEditField.Limits = [0 Inf];
            app.LagWindowEditField.Value = 1;
            app.TaoMaxEditFieldLabel = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.TaoMaxEditFieldLabel.Text = 'Tao Max';
            app.TaoMaxEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.TaoMaxEditField.Limits = [0 Inf];
            app.TaoMaxEditField.Value = 3;
            app.FrequencyPanel = uipanel(app.Program);
            app.FrequencyPanel.Title = 'Frequency';
            app.AllowTimeColumnCheckBox = uicheckbox(app.FrequencyPanel);
            app.AllowTimeColumnCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllowTimeColumnCheckBoxValueChanged, true);
            app.AllowTimeColumnCheckBox.Text = 'Allow Time Column';
            app.AcqRateEditFieldLabel = uilabel(app.FrequencyPanel);
            app.AcqRateEditFieldLabel.Text = 'Acq Rate';
            app.AcqRateEditField = uieditfield(app.FrequencyPanel, 'numeric');
            app.BandPassFilterPanel = uipanel(app.Program);
            app.BandPassFilterPanel.Title = 'Band Pass Filter';
            app.InitialValueEditFieldLabel = uilabel(app.BandPassFilterPanel);
            app.InitialValueEditFieldLabel.Text = 'Initial Value';
            app.InitialFrequencyEditField = uieditfield(app.BandPassFilterPanel, 'numeric');
            app.InitialFrequencyEditField.Limits = [0 Inf];
            app.InitialFrequencyEditField.Enable = 'off';
            app.FinalValueEditFieldLabel = uilabel(app.BandPassFilterPanel);
            app.FinalValueEditFieldLabel.Text = 'Final Value';
            app.FinalFrequencyEditField = uieditfield(app.BandPassFilterPanel, 'numeric');
            app.FinalFrequencyEditField.Limits = [0 Inf];
            app.FinalFrequencyEditField.Enable = 'off';
            app.AllowBandPassCheckBox = uicheckbox(app.BandPassFilterPanel);
            app.AllowBandPassCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllowBandPassCheckBoxValueChanged, true);
            app.AllowBandPassCheckBox.Text = 'Allow Band Pass';
            app.GenerateTVGButton = uibutton(app.Program, 'push');
            app.GenerateTVGButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTVGButtonPushed, true);
            app.GenerateTVGButton.Enable = 'off';
            app.GenerateTVGButton.Text = 'Generate TVG';
            app.NodesPanel = uipanel(app.Program);
            app.NodesPanel.Title = 'Nodes';
            app.NumofNodesEditFieldLabel = uilabel(app.NodesPanel);
            app.NumofNodesEditFieldLabel.Text = 'Num of Nodes';
            app.NumofNodesEditField = uieditfield(app.NodesPanel, 'numeric');
            app.NumofNodesEditField.Limits = [0 Inf];
            app.NumofNodesEditField.Editable = 'off';
            app.NumofNodesEditField.Value = 30;
            app.IndexesPanel = uipanel(app.Program);
            app.IndexesPanel.Title = 'Indexes';
            app.UITable = uitable(app.IndexesPanel);
            app.UITable.ColumnName = {'Indexes'; ''};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.TSCLimitEditFieldLabel = uilabel(app.IndexesPanel);
            app.TSCLimitEditFieldLabel.Text = 'TSC Limit';
            app.TSCLimitEditField = uieditfield(app.IndexesPanel, 'numeric');
            app.TSCLimitEditField.Limits = [0 1];
            app.NodeLocationPanel = uipanel(app.Program);
            app.NodeLocationPanel.Title = 'Node Location';
            app.NodeLocationEditField = uieditfield(app.NodeLocationPanel, 'text');
            app.NodeLocationEditField.Editable = 'off';
            % Show the figure after all components are created
            app.Program.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mosyn

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Program)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Program)
        end
    end
end