classdef CalcThreshold < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalcThresholdUIFigure       matlab.ui.Figure
        MotifSynchronizationPropertiesPanel  matlab.ui.container.Panel
        WindowLengthEditFieldLabel  matlab.ui.control.Label
        WindowLengthEditField       matlab.ui.control.NumericEditField
        TaoMinEditFieldLabel        matlab.ui.control.Label
        TaoMinEditField             matlab.ui.control.NumericEditField
        MotifLagEditFieldLabel      matlab.ui.control.Label
        MotifLagEditField           matlab.ui.control.NumericEditField
        LagWindowEditFieldLabel     matlab.ui.control.Label
        LagWindowEditField          matlab.ui.control.NumericEditField
        TaoMaxEditFieldLabel        matlab.ui.control.Label
        TaoMaxEditField             matlab.ui.control.NumericEditField
        PorcentageEditFieldLabel    matlab.ui.control.Label
        PorcentageEditField         matlab.ui.control.NumericEditField
        PorcentageEditFieldLabel_2  matlab.ui.control.Label
        CalculateThresholdButton    matlab.ui.control.Button
        ThresholdEditFieldLabel     matlab.ui.control.Label
        ThresholdEditField          matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        path 
        file
        threshold
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, path, file, wLength, wLag, taoMin, taoMax, mLag)
            app.path = path;
            app.file = file;
               
            app.WindowLengthEditField.Value = wLength;
            app.TaoMinEditField.Value = taoMin;
            app.TaoMaxEditField.Value = taoMax;
            app.MotifLagEditField.Value = mLag;
            app.LagWindowEditField.Value = wLag;
        end

        % Button pushed function: CalculateThresholdButton
        function CalculateThresholdButtonPushed(app, event)
            file = app.file;
            path = app.path;
            slidWindow = app.WindowLengthEditField.Value;
            taoMin  = app.TaoMinEditField.Value; 
            taoMax  = app.TaoMaxEditField.Value;
            motifLag = app.LagWindowEditField.Value;
            lagWindow = app.LagWindowEditField.Value;
            porc = app.PorcentageEditField.Value;
            
            app.threshold = calcThre(app.path, app.file, slidWindow, lagWindow, taoMin,taoMax, motifLag, porc);
            app.ThresholdEditField.Value = app.threshold;
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalcThresholdUIFigure = uifigure;
            app.CalcThresholdUIFigure.Name = 'CalcThreshold';
            app.MotifSynchronizationPropertiesPanel = uipanel(app.CalcThresholdUIFigure);
            app.MotifSynchronizationPropertiesPanel.Title = 'Motif Synchronization Properties';
            app.WindowLengthEditFieldLabel = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.WindowLengthEditField.Value = 20;
            app.TaoMinEditFieldLabel = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.TaoMinEditFieldLabel.Text = 'Tao Min';
            app.TaoMinEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.TaoMinEditField.Limits = [0 Inf];
            app.MotifLagEditFieldLabel = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.MotifLagEditFieldLabel.Text = 'Motif Lag';
            app.MotifLagEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.MotifLagEditField.Limits = [0 Inf];
            app.MotifLagEditField.Value = 1;
            app.LagWindowEditFieldLabel = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.LagWindowEditFieldLabel.Text = 'Lag Window';
            app.LagWindowEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.LagWindowEditField.Limits = [0 Inf];
            app.LagWindowEditField.Value = 1;
            app.TaoMaxEditFieldLabel = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.TaoMaxEditFieldLabel.Text = 'Tao Max';
            app.TaoMaxEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.TaoMaxEditField.Limits = [0 Inf];
            app.TaoMaxEditField.Value = 3;
            app.PorcentageEditFieldLabel = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.PorcentageEditFieldLabel.Text = 'Porcentage';
            app.PorcentageEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.PorcentageEditField.Limits = [0 Inf];
            app.PorcentageEditField.Value = 95;
            app.PorcentageEditFieldLabel_2 = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.PorcentageEditFieldLabel_2.Text = '%';
            app.CalculateThresholdButton = uibutton(app.CalcThresholdUIFigure, 'push');
            app.CalculateThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateThresholdButtonPushed, true);
            app.CalculateThresholdButton.Text = 'Calculate Threshold';
            app.ThresholdEditFieldLabel = uilabel(app.CalcThresholdUIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.CalcThresholdUIFigure, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CalcThreshold(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalcThresholdUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalcThresholdUIFigure)
        end
    end
end