classdef UISync < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Program              matlab.ui.Figure
        FileMenu             matlab.ui.container.Menu
        SaveProjectMenu      matlab.ui.container.Menu
        AboutMenu            matlab.ui.container.Menu
        TabGroup             matlab.ui.container.TabGroup
        GroupTab             matlab.ui.container.Tab
        GraphicPanelGroup    matlab.ui.container.Panel
        GraphicGroup         matlab.ui.control.UIAxes
        IndexesPanelGroup    matlab.ui.container.Panel
        IndexesGroup         matlab.ui.control.Table
        SubjectTab           matlab.ui.container.Tab
        GraphicPanelSubject  matlab.ui.container.Panel
        GraphicSubject       matlab.ui.control.UIAxes
        IndexesPanelSubject  matlab.ui.container.Panel
        IndexesSubject       matlab.ui.control.Table
        TVGTab               matlab.ui.container.Tab
        GraphicPanelTVG      matlab.ui.container.Panel
        GraphicTVG           matlab.ui.control.UIAxes
        IndexesPanelTVG      matlab.ui.container.Panel
        IndexesTVG           matlab.ui.control.Table
        NetworkPanelTVG      matlab.ui.container.Panel
        SpinnerTVG           matlab.ui.control.Spinner
        SliderTVG            matlab.ui.control.Slider
        NetworkTVG           matlab.ui.control.UIAxes
        REATab               matlab.ui.container.Tab
        NetworkPanelREA      matlab.ui.container.Panel
        SpinnerREA           matlab.ui.control.Spinner
        SliderREA            matlab.ui.control.Slider
        NetworkREA           matlab.ui.control.UIAxes
        GraphicPanelREA      matlab.ui.container.Panel
        GraphicREA           matlab.ui.control.UIAxes
        IndexesPanelREA      matlab.ui.container.Panel
        IndexesREA           matlab.ui.control.Table
        SubjectsPanel        matlab.ui.container.Panel
        Subjects             matlab.ui.control.ListBox
        PropertiesPanel      matlab.ui.container.Panel
        TaoMaxLabel          matlab.ui.control.Label
        TaoMax_Value         matlab.ui.control.Label
        TimeLabel            matlab.ui.control.Label
        Time_Value           matlab.ui.control.Label
        BandPassLabel        matlab.ui.control.Label
        BandPass_Value       matlab.ui.control.Label
        NumNodesLabel        matlab.ui.control.Label
        NumNodes_Value       matlab.ui.control.Label
        MotifLagLabel        matlab.ui.control.Label
        MotifLag_Value       matlab.ui.control.Label
        TaoMinLabel          matlab.ui.control.Label
        TaoMin_Value         matlab.ui.control.Label
        LagWindowLabel       matlab.ui.control.Label
        LagWindow_Value      matlab.ui.control.Label
        ThresholdLabel       matlab.ui.control.Label
        Threshold_Value      matlab.ui.control.Label
        WinLen_Value         matlab.ui.control.Label
        WindowLengthLabel    matlab.ui.control.Label
    end


    properties (Access = public)

    end

    properties (Access = private)
        files
        project
        actualSubject
        
        GroupIndex
        SubjectIndex
        TVGIndex
        REAIndex
        
        TVGNetworkValue
        REANetworkValue
     
    end

    methods (Access = private)
    
        function update(app)
            
            %Group
            GroupPropertiesNames = app.project.Name;

            j = 1;
            for i = 1:length(GroupPropertiesNames)
                prop = GroupPropertiesNames(i);
                prop = prop{1};
                prop = prop(~isspace(prop));
                
                propertyValue = eval(strcat('app.project.Avg', prop(~isspace(prop))));

                if ~isnan(propertyValue)
                    idx(j,:) = {prop, eval(strcat('app.project.Avg', prop(~isspace(prop))))};
                    j = j+1;
                end


            end
        
            prop = GroupPropertiesNames(app.GroupIndex);
            prop = prop{1};
            app.IndexesGroup.Data = idx;
            app.updateGraphicGroup(prop);
            
            %Subject
            SubjectPropertiesNames = app.actualSubject.Name;

            j = 1;
            for i = 1:length(SubjectPropertiesNames)
                prop = SubjectPropertiesNames(i);
                prop = prop{1};
                prop = prop(~isspace(prop));

                mean = eval(strcat('app.actualSubject.Mean',  prop));
                std = eval(strcat('app.actualSubject.Std',  prop));
                cv = eval(strcat('app.actualSubject.CV',  prop));

                if(~isempty(mean) || ~isempty(std) || ~isempty(cv))
                 sidx(j,:) = {prop,  mean, std, cv};
                   j = j+1;
                end

               
            end
            
            prop = SubjectPropertiesNames(app.SubjectIndex);
            prop = prop{1};
            app.IndexesSubject.Data = sidx;
            app.updateGraphicSubject(prop);
            
            %TVG
            TVG = app.actualSubject.TVGArray;
            app.SliderTVG.Limits = [0 length(TVG(1,1,:))];
            app.SliderTVG.MajorTicks  = [0 length(TVG(1,1,:))];
            app.SliderTVG.MajorTickLabels  = {'0',num2str(length(TVG(1,1,:)))} ;
            app.SpinnerTVG.Limits = [0 length(TVG(1,1,:))];
            
            TVGValue = app.TVGNetworkValue;
            
            app.SliderTVG.Value = TVGValue;
            app.SpinnerTVG.Value = app.SliderTVG.Value;
            app.updateNetworkTVG(TVGValue);
            
            
            %REA
            
            REA = app.actualSubject.REA;

            REAValue = app.REANetworkValue;
            app.SliderREA.Value = REAValue;
            app.SpinnerREA.Value = app.SliderREA.Value;
            app.updateNetworkREA(REAValue);
            
        end
        
        function updateGraphicGroup(app, prop)
            histogram(app.GraphicGroup, eval(strcat('[app.project.Files.Mean', prop(~isspace(prop)),']')));
            title(app.GraphicGroup, prop)
        end
    
        function updateGraphicSubject(app, prop)
            histogram(app.GraphicSubject, eval(strcat('[app.actualSubject.MeanFrame',prop(~isspace(prop)),']' )));
            title(app.GraphicSubject, prop)
        end
    
        function updateGraphicTVG(app, prop, value)
            
            TVGIndice = eval(strcat('[app.actualSubject.MeanFrame',prop(~isspace(prop)),']' ));
            interval = 50;
            minValue = value - interval;
            maxValue = value + interval;
            if (minValue < 1)
                minValue = 1;
            end
            if maxValue > length(TVGIndice)
                maxValue = length(TVGIndice);
            end
            
            if value < interval;
                mark = value;
            else
                mark = int32((maxValue - minValue)/2);
            end
            
            TVGIndexInterval = TVGIndice(minValue:maxValue);
            
            plot(app.GraphicTVG, TVGIndexInterval,'-x','MarkerSize',15,'MarkerEdgeColor','red','MarkerIndices',mark);
            title(app.GraphicTVG, strcat(prop, ' Distribution'));
        end
    
        function updateGraphicREA(app, prop)
            REAIndice = eval(strcat('app.actualSubject.REA.',prop(~isspace(prop))));
            
            plot(app.GraphicREA, REAIndice);

            title(app.GraphicREA, strcat(prop, ' Distribution'));
        end
        
        function updateNetworkTVG(app, value)

            SubjectPropertiesNames = app.actualSubject.Name;

            j = 1;
            for i = 1:length(SubjectPropertiesNames)
                prop = SubjectPropertiesNames(i);
                prop = prop{1};
                propertyValue = eval(strcat('app.actualSubject.Mean',  prop(~isspace(prop))));

                if ~isempty(propertyValue)
                    idx(j,:) = {prop, propertyValue};
                    j = j+1;
                end
               
            end
            app.IndexesTVG.Data = idx;
            
            TVG = app.actualSubject.TVGArray;

            mat = TVG(:,:,value);
            numNodes = length(mat);
            g = graph(mat);
            labels = app.actualSubject.NodesLabels;
            location = app.project.NodeLocation;
            serie = 1:numNodes;
            if (isempty(location))
                coord = getNodeLocations(labels,'Location32.txt');
                x = coord(:,1);
                y = coord(:,2);
                h = plot(app.NetworkTVG, g, 'XData',x,'YData',y);
            else
                h = plot(app.NetworkTVG, g);
            end
            
            labelnode(h,serie,labels);
            prop = app.actualSubject.Name(app.TVGIndex);
            prop = prop{1};
            app.updateGraphicTVG(prop, value);
            
            
        end
    
        function updateNetworkREA(app, value)
            
            REAProperties = app.actualSubject.REA.Name;
            
            for i = 1:length(REAProperties)
                prop = REAProperties(i);
                prop = prop{1};
              
                idx(i,:) = {prop, mean(eval(strcat('app.actualSubject.REA.', prop(~isspace(prop)))))};
                
            end
            app.IndexesREA.Data = idx;
           
            REA = app.actualSubject.REA.A;
            UREA = unique(REA);
            UREA(UREA == 0) = [];
            REA(REA < UREA(value)) = 0;
            numNodes = length(REA);
            g = graph(REA);
            app.SliderREA.Limits = [1 length(UREA)];
            weights =  g.Edges.Weight;
            NormWei = weights./max(weights);
            labels = app.actualSubject.NodesLabels;
            coord = getNodeLocations(labels,'Location32.txt');
            x = coord(:,1);
            y = coord(:,2);
            cm = colormap(app.NetworkREA, winter);
            for yy=1:length(NormWei)
                colorID(yy,1) = max(1, sum(NormWei(yy,1) > [0:1/length(cm(:,1)):1]));
            end
            myColor = cm(colorID, :);
            serie = 1:numNodes;
            
            h = plot(app.NetworkREA, g, 'XData',x,'YData',y, 'LineWidth', (NormWei) * 10, 'EdgeColor', myColor);
            labelnode(h,serie,labels);

            prop = REAProperties(app.REAIndex);
            prop = prop{1};
            app.updateGraphicREA(prop);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, project)
            
            app.NetworkREA.XTick = [];
            app.NetworkREA.YTick = [];
            
            app.NetworkTVG.XTick = [0:1:100];
            app.NetworkTVG.YTick = [];
            
            app.files = project.FileNames;
            app.WinLen_Value.Text = num2str(project.Files(1).SlidWindow);
            app.Threshold_Value.Text = num2str(project.Files(1).Threshold);
            app.TaoMin_Value.Text = num2str(project.Files(1).TaoMin);
            app.TaoMax_Value.Text = num2str(project.Files(1).TaoMax);
            app.NumNodes_Value.Text = num2str(project.Files(1).NumNodes);
            app.Time_Value.Text = [num2str(project.Files(1).InitialTime) '~' num2str(project.Files(1).FinalTime)];
            app.Subjects.Items = app.files;
            
            app.project = project;
            app.actualSubject = app.project.Files(1);
            
            app.GroupIndex = 1;
            app.SubjectIndex = 1;
            app.TVGIndex = 1;
            app.REAIndex = 1;

            app.TVGNetworkValue = 1;
            app.REANetworkValue = 1;
            app.update();


        end

        % Menu selected function: SaveProjectMenu
        function SaveProjectMenuSelected(app, event)
            [file,path,indx] = uiputfile('*.mat', 'Save Project', 'project.mat');
            if indx > 0
                project = app.project;
                save(strcat(path,file),'project', '-v7.3');
            end
        end

        % Value changing function: SpinnerREA
        function AllowTimeColumnCheckBoxValueChanged(app, event)
            
        end

        % Callback function
        function CancelButtonPushed(app, event)
            close(app.Program);
        end

        % Callback function
        function OpenButtonPushed(app, event)
            
        end

        % Callback function
        function DirectoryEditFieldValueChanged(app, event)
            
        end

        % Value changed function: Subjects
        function SubjectsValueChanged(app, event)

            value = app.Subjects.Value;
            [~,idx] = ismember(value,app.files);
            app.actualSubject = app.project.Files(idx);
            app.update();
        end

        % Value changed function: SliderTVG
        function SliderTVGValueChanged(app, event)
            value = round(app.SliderTVG.Value);
            app.SpinnerTVG.Value = value;
            app.TVGNetworkValue = value;
            app.updateNetworkTVG(value);
        end

        % Cell selection callback: IndexesGroup
        function IndexesGroupCellSelection(app, event)
            app.GroupIndex = event.Indices(1);
            prop = app.project.Name(app.GroupIndex);
            prop = prop{1};
            app.updateGraphicGroup(prop);
        end

        % Value changed function: SpinnerTVG
        function SpinnerTVGValueChanged(app, event)
            value = round(app.SpinnerTVG.Value);
            app.SliderTVG.Value = value;
            app.TVGNetworkValue = value;
            app.updateNetworkTVG(value);
        end

        % Cell edit callback: IndexesSubject
        function IndexesSubjectCellEdit(app, event)
            
        end

        % Cell selection callback: IndexesSubject
        function IndexesSubjectCellSelection(app, event)
            app.SubjectIndex = event.Indices(1);
            prop = app.actualSubject.Name( app.SubjectIndex);
            prop = prop{1};
            app.updateGraphicSubject(prop);
        end

        % Cell selection callback: IndexesTVG
        function IndexesTVGCellSelection(app, event)
             app.TVGIndex = event.Indices(1);
             app.updateNetworkTVG(app.TVGNetworkValue);
        end

        % Callback function
        function SliderREAValueChanging(app, event)
            
        end

        % Value changed function: SliderREA
        function SliderREAValueChanged(app, event)
            value = round(app.SliderREA.Value);
            app.SpinnerREA.Value = value;
            app.REANetworkValue = value;
            app.updateNetworkREA(value);
        end

        % Value changed function: SpinnerREA
        function SpinnerREAValueChanged(app, event)
            value = round(app.SpinnerREA.Value);
            app.SliderREA.Value = value;
            app.REANetworkValue = value;
            app.updateNetworkREA(value);
        end

        % Cell selection callback: IndexesREA
        function IndexesREACellSelection(app, event)
             app.REAIndex = event.Indices(1);
             app.updateNetworkREA(app.REANetworkValue);
        end

        % Size changed function: TVGTab
        function TVGTabSizeChanged(app, event)
            position = app.TVGTab.Position;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Program = uifigure('Visible', 'off');
            colormap(app.Program, 'parula');
            app.Program.Name = 'MoSyn - Motif Synchronization';
            app.FileMenu = uimenu(app.Program);
            app.FileMenu.Text = 'File';
            app.SaveProjectMenu = uimenu(app.FileMenu);
            app.SaveProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
            app.SaveProjectMenu.Separator = 'on';
            app.SaveProjectMenu.Accelerator = 's';
            app.SaveProjectMenu.Text = 'Save Project';
            app.AboutMenu = uimenu(app.Program);
            app.AboutMenu.Text = 'About';
            app.PropertiesPanel = uipanel(app.Program);
            app.PropertiesPanel.Title = 'Properties';
            app.WindowLengthLabel = uilabel(app.PropertiesPanel);
            app.WindowLengthLabel.Text = 'Window Length';
            app.WinLen_Value = uilabel(app.PropertiesPanel);
            app.WinLen_Value.Text = '0';
            app.Threshold_Value = uilabel(app.PropertiesPanel);
            app.Threshold_Value.Text = '0';
            app.ThresholdLabel = uilabel(app.PropertiesPanel);
            app.ThresholdLabel.Text = 'Threshold';
            app.LagWindow_Value = uilabel(app.PropertiesPanel);
            app.LagWindow_Value.Text = '0';
            app.LagWindowLabel = uilabel(app.PropertiesPanel);
            app.LagWindowLabel.Text = 'Lag Window';
            app.TaoMin_Value = uilabel(app.PropertiesPanel);
            app.TaoMin_Value.Text = '0';
            app.TaoMinLabel = uilabel(app.PropertiesPanel);
            app.TaoMinLabel.Text = 'Tao Min';
            app.MotifLag_Value = uilabel(app.PropertiesPanel);
            app.MotifLag_Value.Text = '0';
            app.MotifLagLabel = uilabel(app.PropertiesPanel);
            app.MotifLagLabel.Text = 'Motif Lag';
            app.NumNodes_Value = uilabel(app.PropertiesPanel);
            app.NumNodes_Value.Text = '0';
            app.NumNodesLabel = uilabel(app.PropertiesPanel);
            app.NumNodesLabel.Text = 'Num Nodes';
            app.BandPass_Value = uilabel(app.PropertiesPanel);
            app.BandPass_Value.Text = '0~100';
            app.BandPassLabel = uilabel(app.PropertiesPanel);
            app.BandPassLabel.Text = 'Band Pass';
            app.Time_Value = uilabel(app.PropertiesPanel);
            app.Time_Value.Text = '0~63000';
            app.TimeLabel = uilabel(app.PropertiesPanel);
            app.TimeLabel.Text = 'Time';
            app.TaoMax_Value = uilabel(app.PropertiesPanel);
            app.TaoMax_Value.Text = '0';
            app.TaoMaxLabel = uilabel(app.PropertiesPanel);
            app.TaoMaxLabel.Text = 'Tao Max';
            app.SubjectsPanel = uipanel(app.Program);
            app.SubjectsPanel.Title = 'Subjects';
            app.Subjects = uilistbox(app.SubjectsPanel);
            app.Subjects.Items = {};
            app.Subjects.ValueChangedFcn = createCallbackFcn(app, @SubjectsValueChanged, true);
            app.Subjects.Value = {};
            app.TabGroup = uitabgroup(app.Program);
            app.GroupTab = uitab(app.TabGroup);
            app.GroupTab.Title = 'Group';
            app.IndexesPanelGroup = uipanel(app.GroupTab);
            app.IndexesPanelGroup.Title = 'Indexes';
            app.IndexesGroup = uitable(app.IndexesPanelGroup);
            app.IndexesGroup.ColumnName = {'Indexes'; 'Mean'};
            app.IndexesGroup.RowName = {};
            app.IndexesGroup.CellSelectionCallback = createCallbackFcn(app, @IndexesGroupCellSelection, true);
            app.GraphicPanelGroup = uipanel(app.GroupTab);
            app.GraphicPanelGroup.Title = 'Graphic';
            app.GraphicGroup = uiaxes(app.GraphicPanelGroup);
            title(app.GraphicGroup, 'Degree')
            app.SubjectTab = uitab(app.TabGroup);
            app.SubjectTab.Title = 'Subject';
            app.IndexesPanelSubject = uipanel(app.SubjectTab);
            app.IndexesPanelSubject.Title = 'Indexes';
            app.IndexesSubject = uitable(app.IndexesPanelSubject);
            app.IndexesSubject.ColumnName = {'Indexes'; 'Mean'; 'STD'; 'CV'};
            app.IndexesSubject.RowName = {};
            app.IndexesSubject.ColumnEditable = true;
            app.IndexesSubject.CellEditCallback = createCallbackFcn(app, @IndexesSubjectCellEdit, true);
            app.IndexesSubject.CellSelectionCallback = createCallbackFcn(app, @IndexesSubjectCellSelection, true);
            app.GraphicPanelSubject = uipanel(app.SubjectTab);
            app.GraphicPanelSubject.Title = 'Graphic';
            app.GraphicSubject = uiaxes(app.GraphicPanelSubject);
            title(app.GraphicSubject, 'Degree')
            app.TVGTab = uitab(app.TabGroup);
            app.TVGTab.SizeChangedFcn = createCallbackFcn(app, @TVGTabSizeChanged, true);
            app.TVGTab.Title = 'TVG';
            app.NetworkPanelTVG = uipanel(app.TVGTab);
            app.NetworkPanelTVG.Title = 'Network';
            app.NetworkTVG = uiaxes(app.NetworkPanelTVG);
            app.SliderTVG = uislider(app.NetworkPanelTVG);
            app.SliderTVG.Limits = [0 60000];
            app.SliderTVG.MajorTickLabels = {'0', '1.2e+04', '2.4e+04', '3.6e+04', '4.8e+04', '6e+04'};
            app.SliderTVG.ValueChangedFcn = createCallbackFcn(app, @SliderTVGValueChanged, true);
            app.SliderTVG.MinorTicks = [];
            app.SpinnerTVG = uispinner(app.NetworkPanelTVG);
            app.SpinnerTVG.Step = 0.5;
            app.SpinnerTVG.Limits = [0 Inf];
            app.SpinnerTVG.ValueDisplayFormat = '%.0f';
            app.SpinnerTVG.ValueChangedFcn = createCallbackFcn(app, @SpinnerTVGValueChanged, true);
            app.IndexesPanelTVG = uipanel(app.TVGTab);
            app.IndexesPanelTVG.Title = 'Indexes';
            app.IndexesTVG = uitable(app.IndexesPanelTVG);
            app.IndexesTVG.ColumnName = {'Indexes'; 'Values'};
            app.IndexesTVG.RowName = {};
            app.IndexesTVG.CellSelectionCallback = createCallbackFcn(app, @IndexesTVGCellSelection, true);
            app.GraphicPanelTVG = uipanel(app.TVGTab);
            app.GraphicPanelTVG.Title = 'Graphic';
            app.GraphicTVG = uiaxes(app.GraphicPanelTVG);
            title(app.GraphicTVG, 'Degree')
            app.REATab = uitab(app.TabGroup);
            app.REATab.Title = 'REA';
            app.IndexesPanelREA = uipanel(app.REATab);
            app.IndexesPanelREA.Title = 'Indexes';
            app.IndexesREA = uitable(app.IndexesPanelREA);
            app.IndexesREA.ColumnName = {'Indexes'; 'Values'};
            app.IndexesREA.RowName = {};
            app.IndexesREA.CellSelectionCallback = createCallbackFcn(app, @IndexesREACellSelection, true);
            app.GraphicPanelREA = uipanel(app.REATab);
            app.GraphicPanelREA.Title = 'Graphic';
            app.GraphicREA = uiaxes(app.GraphicPanelREA);
            app.NetworkPanelREA = uipanel(app.REATab);
            app.NetworkPanelREA.Title = 'Network';
            app.NetworkREA = uiaxes(app.NetworkPanelREA);
            app.SliderREA = uislider(app.NetworkPanelREA);
            app.SliderREA.Limits = [1 60000];
            app.SliderREA.MajorTicks = [];
            app.SliderREA.MajorTickLabels = {};
            app.SliderREA.ValueChangedFcn = createCallbackFcn(app, @SliderREAValueChanged, true);
            app.SliderREA.MinorTicks = [];
            app.SliderREA.Value = 1;
            app.SpinnerREA = uispinner(app.NetworkPanelREA);
            app.SpinnerREA.ValueChangingFcn = createCallbackFcn(app, @AllowTimeColumnCheckBoxValueChanged, true);
            app.SpinnerREA.Limits = [0 Inf];
            app.SpinnerREA.ValueDisplayFormat = '%.0f';
            app.SpinnerREA.ValueChangedFcn = createCallbackFcn(app, @SpinnerREAValueChanged, true);
            % Show the figure after all components are created
            app.Program.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UISync(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Program)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Program)
        end
    end
end