classdef MosynGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Program                       matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        OpenProjectMenu               matlab.ui.container.Menu
        LoadNodeLocationsMenu         matlab.ui.container.Menu
        CalculateThresholdMenu        matlab.ui.container.Menu
        ConfigureMenu                 matlab.ui.container.Menu
        CalculateMeasuresMenu         matlab.ui.container.Menu
        RemoveTimeColumnCheckBox      matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        DirectoryPanel                matlab.ui.container.Panel
        DirectoryEditField            matlab.ui.control.EditField
        Open                          matlab.ui.control.Button
        NodeLocationPanel             matlab.ui.container.Panel
        NodeLocationEditField         matlab.ui.control.EditField
        FilesPanel                    matlab.ui.container.Panel
        FilesTable                    matlab.ui.control.Table
        BandPassFilterPanel           matlab.ui.container.Panel
        AllowBandPassCheckBox         matlab.ui.control.CheckBox
        InitialFrequencyEditField     matlab.ui.control.NumericEditField
        FinalFrequencyEditField       matlab.ui.control.NumericEditField
        AcqRateEditField              matlab.ui.control.NumericEditField
        AcqRateEditFieldLabel         matlab.ui.control.Label
        FinalValueEditFieldLabel      matlab.ui.control.Label
        InitialValueEditFieldLabel    matlab.ui.control.Label
        TimeandFrequencyPanel         matlab.ui.container.Panel
        InitialTimeEditField          matlab.ui.control.NumericEditField
        FinalTimeEditField            matlab.ui.control.NumericEditField
        FinalTimeEditFieldLabel       matlab.ui.control.Label
        RealFinalTimeCheckBox         matlab.ui.control.CheckBox
        InitialTimeEditFieldLabel     matlab.ui.control.Label
        TSCPanel                      matlab.ui.container.Panel
        TSCLimitEditField             matlab.ui.control.NumericEditField
        TSCLimitEditFieldLabel        matlab.ui.control.Label
        MotifSynchronizationPropertiesPanel  matlab.ui.container.Panel
        WindowLengthEditField         matlab.ui.control.NumericEditField
        MotifLagEditField_2Label      matlab.ui.control.Label
        ThresholdEditField            matlab.ui.control.NumericEditField
        LagWindowEditField            matlab.ui.control.NumericEditField
        TaoMinEditField               matlab.ui.control.NumericEditField
        TaoMaxEditField               matlab.ui.control.NumericEditField
        TaoMaxEditFieldLabel          matlab.ui.control.Label
        TaoMinEditFieldLabel          matlab.ui.control.Label
        LagWindowEditField_3Label     matlab.ui.control.Label
        MotifLagEditField             matlab.ui.control.NumericEditField
        ThresholdEditField_2Label     matlab.ui.control.Label
        WindowLengthEditField_2Label  matlab.ui.control.Label
        MeasuresPanel                 matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        GenerateTVGButton             matlab.ui.control.Button
    end


    properties (Access = private)
        Files
        Path
        Location

        MeasureConfigurationButton

    end

    methods (Access = private)

        function value = ensureString(~, value)
            if isempty(value) || ~isstring(value)
                value = "";
            end
        end

        function updateFilesTableAndEnableButton(app)
            if ~isempty(app.Files) && ~isstring(app.Files)
                app.Files = convertToStringArray(app, app.Files);
                nodes = cellfun(@(file) getNumberOfNodes(fullfile(app.Path, file)), app.Files, 'UniformOutput', false);
                app.FilesTable.Data = [app.Files' nodes'];
                app.GenerateTVGButton.Enable = 'on';
            end
        end

        function stringArray = convertToStringArray(~, array)
            stringArray = string(array);
        end

        function configureFields(app)

            filename = strcat(app.Path, app.Files{1});
            [~,~,ext] = fileparts(filename);

            % Check the file extension. If it is a .mat file, load the configuration from it.
            % Otherwise, get the saved values.
            if strcmp(ext, ".mat")
                app.loadConfigurationFromMatFile(filename)
            else
                app.getSavedValues
            end

        end

        function loadConfigurationFromMatFile (app, filename)

            load (filename, 'initialTime', 'finalTime', 'realTime', 'initialFrequency', 'finalFrequency', 'hasFrequency', 'removeTimeColumn', 'acqRate', 'slidWindow', 'taoMin', 'taoMax', 'threshold', 'numNodes' )
            %
            % uiMap = {
            %     'removeTimeColumn', 'RemoveTimeColumnCheckBox', 'Checked';
            %     'acqRate', 'AcqRateEditField', 'Value';
            %     'hasFrequency', 'AllowBandPassCheckBox', 'Value';
            %     'initialFrequency', 'InitialFrequencyEditField', 'Value';
            %     'finalFrequency', 'FinalFrequencyEditField', 'Value';
            %     'initialTime', 'InitialTimeEditField', 'Value';
            %     'finalTime', 'FinalTimeEditField', 'Value';
            %     'realTime', 'RealFinalTimeCheckBox', 'Value';
            %     'slidWindow', 'WindowLengthEditField', 'Value';
            %     'threshold', 'ThresholdEditField', 'Value';
            %     'taoMin', 'TaoMinEditField', 'Value';
            %     'taoMax', 'TaoMaxEditField', 'Value'
            % };
            %
            % for i = 1:size(uiMap, 1)
            %     varName = uiMap{i, 1};
            %     uiComponent = uiMap{i, 2};
            %     uiProperty = uiMap{i, 3};
            %
            %     if isfield(loadedVars, varName)
            %         app.(uiComponent).(uiProperty) = loadedVars.(varName);
            %         % Desativar o campo, já que os valores estão sendo carregados do arquivo
            %         if isprop(app.(uiComponent), 'Enable')
            %             app.(uiComponent).Enable = 'off';
            %         end
            %     end
            % end
            app.RemoveTimeColumnCheckBox.Checked = removeTimeColumn;
            app.RemoveTimeColumnCheckBox.Enable = 'off';

            app.AcqRateEditField.Value = acqRate;
            app.AcqRateEditField.Enable = 'off';

            app.AllowBandPassCheckBox.Value = hasFrequency;
            app.AllowBandPassCheckBox.Enable = 'off';

            app.InitialFrequencyEditField.Value = initialFrequency;
            app.InitialFrequencyEditField.Enable = 'off';

            app.FinalFrequencyEditField.Value = finalFrequency;
            app.FinalFrequencyEditField.Enable = 'off';

            app.InitialTimeEditField.Value = initialTime;
            app.InitialTimeEditField.Enable = 'off';

            app.FinalTimeEditField.Value = finalTime;
            app.FinalTimeEditField.Enable = 'off';

            app.RealFinalTimeCheckBox.Value = realTime;
            app.RealFinalTimeCheckBox.Enable = 'off';

            app.WindowLengthEditField.Value = slidWindow;
            app.WindowLengthEditField.Enable = 'off';

            app.ThresholdEditField.Value = threshold;
            app.ThresholdEditField.Enable = 'off';

            app.TaoMinEditField.Value = taoMin;
            app.TaoMinEditField.Enable = 'off';

            app.TaoMaxEditField.Value = taoMax;
            app.TaoMaxEditField.Enable = 'off';

        end

        function getSavedValues(app)

            % Enable UI components that can be edited by the user
            uiComponentsToEnable = {'RemoveTimeColumnCheckBox', 'InitialTimeEditField', 'FinalTimeEditField', 'RealFinalTimeCheckBox', 'WindowLengthEditField', 'ThresholdEditField', 'TaoMinEditField', 'TaoMaxEditField'};
            app.enableUIComponents(uiComponentsToEnable, true);

            % Update the file table if there are files available
            if (~isempty(app.Files) && ~isstring(app.Files))
                nodes = cellfun(@getNumberOfNodes, strcat(app.Path,'\', app.Files), 'UniformOutput', false);
                app.FilesTable.Data = [app.Files' nodes'];
            end

            % Mapping of environment variables to app components
            envVarMap = {
                'Motif_AcqRate', 'AcqRateEditField';
                'Motif_InitialTime', 'InitialTimeEditField';
                'Motif_FinalTime', 'FinalTimeEditField';
                'Motif_WindowLength', 'WindowLengthEditField';
                'Motif_Threshold', 'ThresholdEditField';
                'Motif_TaoMin', 'TaoMinEditField';
                'Motif_TaoMax', 'TaoMaxEditField';
                'Motif_MotifLagValue', 'MotifLagEditField';
                'Motif_LagWindow', 'LagWindowEditField';
                'Motif_TSCLim', 'TSCLimitEditField'
                };

            % Update the values of UI components based on the environment variables
            for i = 1:size(envVarMap, 1)
                envVar = envVarMap{i, 1};
                uiComponent = envVarMap{i, 2};
                envValue = getenv(envVar);

                if ~isempty(envValue)
                    app.(uiComponent).Value = str2double(envValue);
                end
            end

        end

        function enableUIComponents(app, components, enable)
            % for i = 1:length(components)
            %     component = components{i};
            %     app.(component).Enable = enable ? 'on' : 'off';
            % end
        end

        function configureMeasureTable(app)

            measureRegistry = MeasureRegistry.getInstance();

            app.UITable.Data = measureRegistry.getMeasuresProperties({'name', 'active'});
        end

        function setEnvironmentVariables(~, configurationValues)
            % Iterate through the configuration values and set the corresponding environment variables
            for i = 1:size(configurationValues, 1)
                key = configurationValues{i, 1};
                value = configurationValues{i, 2};
                setenv(['Motif_' key], num2str(value));
            end
        end

        function configuration = createConfiguration(app, configurationValues)
            % Convert configurationValues to a containers.Map
            configMap = containers.Map(configurationValues(:, 1), configurationValues(:, 2));

            % Extract the configuration values using the parameter names
            initialTime = configMap('InitialTime');
            finalTime = configMap('FinalTime');
            realTime = configMap('FinalTime');
            initialFrequency = configMap('InitialFrequency');
            finalFrequency = configMap('FinalFrequency');
            timeColumn = configMap('TimeColumn');
            acqRate = configMap('AcqRate');
            slidWindow = configMap('SlidWindow');
            taoMin = configMap('TaoMin');
            taoMax = configMap('TaoMax');
            threshold = configMap('Threshold');
            tscLim = configMap('TSCLim');
         

            % Create the configuration object
            configuration = Configuration(app.Path, app.Files, app.Location, initialTime, finalTime, realTime, initialFrequency, finalFrequency, timeColumn, acqRate, ...
                slidWindow, taoMin, taoMax, threshold, tscLim);

        end

        function updateGlobalVariables(app)
            % Update the global variables with the selected files and path
            global globalFiles globalPath;

            globalFiles = app.Files;
            globalPath = app.Path;
        
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Access the global variables for files and path.
            global globalFiles globalPath;

            % Ensure that globalFiles and globalPath are strings.
            % If they are empty or numeric, they will be set to an empty
            % string.
            globalFiles = ensureString(app,globalFiles);
            globalPath = ensureString(app, globalPath);

            % Assign the global variables to the app's properties.
            app.Files = globalFiles;
            app.Path = globalPath;

            % Set the value of the path to the Directory field.
            app.DirectoryEditField.Value = app.Path;

            % Update the files table and enable the Generate TVG button if applicable.
            updateFilesTableAndEnableButton(app);

            % Configure the measure table with the required settings.
            app.configureMeasureTable

            app.configureFields
        end

        % Menu selected function: OpenProjectMenu
        function OpenProjectMenuSelected(app, event)
            % Display a file selection dialog box allowing the user to select a .mat file
            [selectedFile,selectedPath,indx] = uigetfile('*.mat');

            % Check if a file was selected
            if indx > 0

                % Construct the full path to the selected .mat file
                fullFilePath = fullfile(selectedPath, selectedFile);

                % Load the .mat file
                matData = load(fullFilePath);

                if isfield(matData, 'project')
                    % Retrieve the 'project' variable from the .mat file
                    project = matData.project;

                    % Synchronize the UI with the loaded project data
                    UISync(project);
                else
                    % Display an error if the 'project' variable is not found in the .mat file
                    error('The selected file does not contain a ''project'' variable.');
                end

                % Update the app's Files and Path properties
                app.Files = selectedFile;
                app.Path = selectedPath;
            end

        end

        % Button pushed function: GenerateTVGButton
        function GenerateTVGButtonPushed(app, event)

            % Retrieve values from the app's UI components
            configurationValues = {
                'InitialTime', app.InitialTimeEditField.Value;
                'FinalTime', app.FinalTimeEditField.Value;
                'SlidWindow', app.WindowLengthEditField.Value;
                'TaoMin', app.TaoMinEditField.Value;
                'TaoMax', app.TaoMaxEditField.Value;
                'Threshold', app.ThresholdEditField.Value;
                'MotifLag', app.LagWindowEditField.Value;
                'LagWindow', app.LagWindowEditField.Value;
                'RealTime', app.RealFinalTimeCheckBox.Value;
                'InitialFrequency', app.InitialFrequencyEditField.Value;
                'FinalFrequency', app.FinalFrequencyEditField.Value;
                'HasFrequency', app.AllowBandPassCheckBox.Value;
                'TimeColumn', app.RemoveTimeColumnCheckBox.Checked;
                'AcqRate', app.AcqRateEditField.Value;
                'TSCLim', app.TSCLimitEditField.Value
                };

            % Set the environment variables based on the configuration values
            setEnvironmentVariables(app, configurationValues);

            % Disable the Generate TVG Button to prevent multiple clicks
            app.GenerateTVGButton.Enable = 'off';

            % Create and run the project configuration
            try
                configuration = createConfiguration(app, configurationValues);
                project = Project(configuration);
                project.run;
            catch error
                % If an error occurs, enable the button again and display the error
                app.GenerateTVGButton.Enable = 'on';
                multiWaitbar('CloseAll');
                rethrow(error);
            end

             % Re-enable the Generate TVG Button after the operation
            app.GenerateTVGButton.Enable = 'on';

            % Sync the UI with the project if the project is successfully created
            if (project ~= -1)
                UISync(project);
            end
        end

        % Button pushed function: Open
        function OpenButtonPushed(app, event)

            % Display a file selection dialog box allowing the user to select data files
            [files, path] = uigetfile({
                '*.asc;*.csv', 'Data Files (*.asc;*.csv)'; 
                '*.tvg.mat', 'TVG Files (tvg.mat)'
            }, 'Select one or more files', 'MultiSelect', 'on', app.Path);

            % Check if any file was selected
            if isequal(files, 0)
                % No file was selected, exit the function
                return;
            end

            if iscell(files)
                % Multiple files were selected
                app.Files = files;
            else
                % A single file was selected
                app.Files = {files};  % Ensure app.Files is always a cell array
            end
            app.Path = path;

             % Update global variables
            app.updateGlobalVariables();

            app.configureFields();

            % Update the FilesTable with the node information if files are available
            if (~isempty(app.Files) && ~isstring(app.Files))
                app.Files = string(app.Files);  % Ensure files are treated as string array
                nodes = cellfun(@(file) getNumberOfNodes(fullfile(app.Path, file)), app.Files, 'UniformOutput', false);
                app.FilesTable.Data = [app.Files' nodes'];
                app.GenerateTVGButton.Enable = 'on';
            end

            % Update the DirectoryEditField with the selected path
            app.DirectoryEditField.Value = app.Path;

        end

        % Value changed function: DirectoryEditField
        function DirectoryEditFieldValueChanged(app, event)

            % Retrieve the new directory path from the DirectoryEditField
            path = app.DirectoryEditField.Value;

            % Ensure the path ends with a file separator for consistent file path construction
            if ~endsWith(path, filesep)
                path = [path filesep];
            end

            app.Path = path;

            % Get the directory data for files with .asc extension
            dirData = dir([value '*.asc']);
            % Extract the file names that are not directories
            app.Files = {dirData(~[dirData.isdir]).name}';

            % Check if files are available and not already strings
            if (~isempty(app.Files) && ~isstring(app.Files))
                app.Files = string(app.Files);  % Ensure files are treated as string array
                % Construct full file paths and retrieve node information
                fullFilePaths = fullfile(app.Path, app.Files);
                nodes = cellfun(@getNumberOfNodes, fullFilePaths, 'UniformOutput', false);
                % Update the FilesTable with the file names and node information
                app.FilesTable.Data = [app.Files' nodes'];
                % Enable the Generate TVG Button
                app.GenerateTVGButton.Enable = 'on';
            end
        end

        % Value changed function: RealFinalTimeCheckBox
        function RealFinalTimeCheckBoxValueChanged(app, event)
             app.FinalTimeEditField.Enable = app.RealFinalTimeCheckBox.Value ~= 1;
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox({'MoSyn - Motif Synchronization and Complex Network Analysis 4.0';'Developed by Neuro Redes (UFBA)';'Author: Marcos Netto';'Mail: mpnetto88@gmail.com'});
        end

        % Menu selected function: LoadNodeLocationsMenu
        function LoadNodeLocationsMenuSelected(app, event)
            % Open a file selection dialog for the user to select a .txt file
            [file, path, indx] = uigetfile('*.txt');

            % Workaround for a known bug where the app window minimizes unexpectedly.
            app.Program.Visible = 'off';
            app.Program.Visible = 'on';

          % Check if a file was selected
            if indx > 0
                app.Location = fullfile(path, file);
                app.NodeLocationEditField.Value = app.Location;
            end
        end

        % Value changed function: AllowBandPassCheckBox
        function AllowBandPassCheckBoxValueChanged(app, event)
            % Enable or disable the frequency and acquisition rate fields based on the AllowBandPassCheckBox value
            if app.AllowBandPassCheckBox.Value == 1
                % If checkbox is checked, enable the frequency and acquisition rate fields
                app.InitialFrequencyEditField.Enable = 'on';
                app.FinalFrequencyEditField.Enable = 'on';
                app.AcqRateEditField.Enable = 'on';
            else
                % If checkbox is not checked, disable the frequency and acquisition rate fields
                app.InitialFrequencyEditField.Enable = 'off';
                app.FinalFrequencyEditField.Enable = 'off';
                app.AcqRateEditField.Enable = 'off';
            end
        end

        % Menu selected function: CalculateThresholdMenu
        function CalculateThresholdMenuSelected(app, event)
            % Retrieve the parameter values from the UI components
            slidWindow = app.WindowLengthEditField.Value;
            taoMin  = app.TaoMinEditField.Value;
            taoMax  = app.TaoMaxEditField.Value;
            motifLag = app.LagWindowEditField.Value;
            lagWindow = app.LagWindowEditField.Value;

            % Check if at least one file is loaded and the file names are not strings
            if (~isempty(app.Files) && ~isstring(app.Files))
                % Convert the file names to string array if necessary
                app.Files = string(app.Files);

                % Call the function to calculate the threshold based on the first file and parameters
                thresholdApp = CalcThreshold(app.Path, app.Files(1), slidWindow, lagWindow, taoMin, taoMax, motifLag);

                % Wait for the threshold calculation to complete
                waitfor(thresholdApp);

                % Display the thresholdApp, if you want to show some graphical output or messages
                display(thresholdApp);
            else
                % If no files are loaded, alert the user
                uialert(app.Program,'You have to load at least one file','No File');
            end
        end

        % Menu selected function: CalculateMeasuresMenu
        function CalculateIndexesClicked(app, event)

            if app.CalculateMeasuresMenu.Checked == 'on'
                app.CalculateMeasuresMenu.Checked = 'off';
            else
                app.CalculateMeasuresMenu.Checked = 'on';
            end

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            % Get the row index of the selected cell
            row = event.Indices(1);

            measureRegistry = MeasureRegistry.getInstance();

            measures = measureRegistry.getMeasuresProperties({'name'});

            name = measures{row};

            % Create a context menu
            menu = uicontextmenu(app.MeasuresPanel.Parent);

            uimenu(menu, 'Label', 'Configuration', 'Callback', @(~,~) disp(['Option 1 selected ' name ]));

            % Associate the context menu with the selected row
            app.UITable.ContextMenu = menu;

        end

        % Menu selected function: RemoveTimeColumnCheckBox
        function RemoveTimeColumnCheckBoxMenuSelected(app, event)
            % Toggle the Checked state of the CalculateMeasuresMenu
            app.CalculateMeasuresMenu.Checked = ~app.CalculateMeasuresMenu.Checked;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % Get the index of the edited cell and the new active state
            index = event.Indices(1);
            active = event.NewData;

            % Get the measure registry instance
            measureRegistry = MeasureRegistry.getInstance();

            % Get the list of measures from the registry
            measures = measureRegistry.getMeasures;

            % Update the active state of the corresponding measure
            measures(index).active = active;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Program = uifigure('Visible', 'off');
            app.Program.Name = 'MoSyn - Motif Synchronization';
            app.FileMenu = uimenu(app.Program);
            app.FileMenu.Text = 'File';
            app.OpenProjectMenu = uimenu(app.FileMenu);
            app.OpenProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenProjectMenuSelected, true);
            app.OpenProjectMenu.Separator = 'on';
            app.OpenProjectMenu.Accelerator = 'p';
            app.OpenProjectMenu.Text = 'Open Project';
            app.LoadNodeLocationsMenu = uimenu(app.FileMenu);
            app.LoadNodeLocationsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadNodeLocationsMenuSelected, true);
            app.LoadNodeLocationsMenu.Separator = 'on';
            app.LoadNodeLocationsMenu.Accelerator = 'L';
            app.LoadNodeLocationsMenu.Text = 'Load Node Locations';
            app.CalculateThresholdMenu = uimenu(app.FileMenu);
            app.CalculateThresholdMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateThresholdMenuSelected, true);
            app.CalculateThresholdMenu.Accelerator = 'T';
            app.CalculateThresholdMenu.Text = 'Calculate Threshold';
            app.ConfigureMenu = uimenu(app.Program);
            app.ConfigureMenu.Text = 'Configure';
            app.CalculateMeasuresMenu = uimenu(app.ConfigureMenu);
            app.CalculateMeasuresMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateIndexesClicked, true);
            app.CalculateMeasuresMenu.Checked = 'on';
            app.CalculateMeasuresMenu.Text = 'Calculate Measures';
            app.RemoveTimeColumnCheckBox = uimenu(app.ConfigureMenu);
            app.RemoveTimeColumnCheckBox.MenuSelectedFcn = createCallbackFcn(app, @RemoveTimeColumnCheckBoxMenuSelected, true);
            app.RemoveTimeColumnCheckBox.Text = 'Remove Time Column';
            app.AboutMenu = uimenu(app.Program);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GenerateTVGButton = uibutton(app.Program, 'push');
            app.GenerateTVGButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTVGButtonPushed, true);
            app.GenerateTVGButton.Enable = 'off';
            app.GenerateTVGButton.Text = 'Generate TVG';
            app.MeasuresPanel = uipanel(app.Program);
            app.MeasuresPanel.Title = 'Measures';
            app.UITable = uitable(app.MeasuresPanel);
            app.UITable.ColumnName = {'Measures'; ''; 'Conf.'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.MotifSynchronizationPropertiesPanel = uipanel(app.Program);
            app.MotifSynchronizationPropertiesPanel.Title = 'Motif Synchronization Properties';
            app.WindowLengthEditField_2Label = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.WindowLengthEditField_2Label.Text = 'Window Length';
            app.ThresholdEditField_2Label = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.ThresholdEditField_2Label.Text = 'Threshold';
            app.MotifLagEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.MotifLagEditField.Limits = [0 Inf];
            app.MotifLagEditField.Value = 1;
            app.LagWindowEditField_3Label = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.LagWindowEditField_3Label.Text = 'Lag Window';
            app.TaoMinEditFieldLabel = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.TaoMinEditFieldLabel.Text = 'Tao Min';
            app.TaoMaxEditFieldLabel = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.TaoMaxEditFieldLabel.Text = 'Tao Max';
            app.TaoMaxEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.TaoMaxEditField.Limits = [0 Inf];
            app.TaoMaxEditField.Value = 3;
            app.TaoMinEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.TaoMinEditField.Limits = [0 Inf];
            app.LagWindowEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.LagWindowEditField.Limits = [0 Inf];
            app.LagWindowEditField.Value = 1;
            app.ThresholdEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 1];
            app.ThresholdEditField.Value = 0.9;
            app.MotifLagEditField_2Label = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.MotifLagEditField_2Label.Text = 'Motif Lag';
            app.WindowLengthEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.WindowLengthEditField.Value = 20;
            app.TSCPanel = uipanel(app.Program);
            app.TSCPanel.Title = 'TSC';
            app.TSCLimitEditFieldLabel = uilabel(app.TSCPanel);
            app.TSCLimitEditFieldLabel.Text = 'TSC Limit';
            app.TSCLimitEditField = uieditfield(app.TSCPanel, 'numeric');
            app.TSCLimitEditField.Limits = [0 1];
            app.TimeandFrequencyPanel = uipanel(app.Program);
            app.TimeandFrequencyPanel.Title = 'Time and Frequency';
            app.InitialTimeEditFieldLabel = uilabel(app.TimeandFrequencyPanel);
            app.InitialTimeEditFieldLabel.Text = 'Initial Time';
            app.RealFinalTimeCheckBox = uicheckbox(app.TimeandFrequencyPanel);
            app.RealFinalTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RealFinalTimeCheckBoxValueChanged, true);
            app.RealFinalTimeCheckBox.Text = 'Real Final Time';
            app.FinalTimeEditFieldLabel = uilabel(app.TimeandFrequencyPanel);
            app.FinalTimeEditFieldLabel.Text = 'Final Time';
            app.FinalTimeEditField = uieditfield(app.TimeandFrequencyPanel, 'numeric');
            app.FinalTimeEditField.Limits = [0 Inf];
            app.FinalTimeEditField.ValueDisplayFormat = '%.0f';
            app.FinalTimeEditField.Value = 1000;
            app.InitialTimeEditField = uieditfield(app.TimeandFrequencyPanel, 'numeric');
            app.InitialTimeEditField.ValueDisplayFormat = '%.0f';
            app.InitialTimeEditField.Value = 1;
            app.BandPassFilterPanel = uipanel(app.Program);
            app.BandPassFilterPanel.Title = 'Band Pass Filter';
            app.InitialValueEditFieldLabel = uilabel(app.BandPassFilterPanel);
            app.InitialValueEditFieldLabel.Enable = 'off';
            app.InitialValueEditFieldLabel.Text = 'Initial Value';
            app.FinalValueEditFieldLabel = uilabel(app.BandPassFilterPanel);
            app.FinalValueEditFieldLabel.Enable = 'off';
            app.FinalValueEditFieldLabel.Text = 'Final Value';
            app.AcqRateEditFieldLabel = uilabel(app.BandPassFilterPanel);
            app.AcqRateEditFieldLabel.Enable = 'off';
            app.AcqRateEditFieldLabel.Text = 'Acq Rate';
            app.AcqRateEditField = uieditfield(app.BandPassFilterPanel, 'numeric');
            app.AcqRateEditField.Enable = 'off';
            app.FinalFrequencyEditField = uieditfield(app.BandPassFilterPanel, 'numeric');
            app.FinalFrequencyEditField.Limits = [0 Inf];
            app.FinalFrequencyEditField.Enable = 'off';
            app.InitialFrequencyEditField = uieditfield(app.BandPassFilterPanel, 'numeric');
            app.InitialFrequencyEditField.Limits = [0 Inf];
            app.InitialFrequencyEditField.Enable = 'off';
            app.AllowBandPassCheckBox = uicheckbox(app.BandPassFilterPanel);
            app.AllowBandPassCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllowBandPassCheckBoxValueChanged, true);
            app.AllowBandPassCheckBox.Text = 'Allow Band Pass';
            app.FilesPanel = uipanel(app.Program);
            app.FilesPanel.Title = 'Files';
            app.FilesTable = uitable(app.FilesPanel);
            app.FilesTable.ColumnName = {'Subjects'; 'Nodes'};
            app.FilesTable.RowName = {};
            app.NodeLocationPanel = uipanel(app.Program);
            app.NodeLocationPanel.Title = 'Node Location';
            app.NodeLocationEditField = uieditfield(app.NodeLocationPanel, 'text');
            app.NodeLocationEditField.Editable = 'off';
            app.DirectoryPanel = uipanel(app.Program);
            app.DirectoryPanel.Title = 'Directory';
            app.Open = uibutton(app.DirectoryPanel, 'push');
            app.Open.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.Open.Text = '...';
            app.DirectoryEditField = uieditfield(app.DirectoryPanel, 'text');
            app.DirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @DirectoryEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.Program.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MosynGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Program)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Program)
        end
    end
end