classdef UISync < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Program              matlab.ui.Figure
        FileMenu             matlab.ui.container.Menu
        SaveProjectMenu      matlab.ui.container.Menu
        AboutMenu            matlab.ui.container.Menu
        TabGroup             matlab.ui.container.TabGroup
        GroupTab             matlab.ui.container.Tab
        GraphicPanelGroup    matlab.ui.container.Panel
        GraphicGroup         matlab.ui.control.UIAxes
        IndexesPanelGroup    matlab.ui.container.Panel
        IndexesGroup         matlab.ui.control.Table
        SubjectTab           matlab.ui.container.Tab
        GraphicPanelSubject  matlab.ui.container.Panel
        GraphicSubject       matlab.ui.control.UIAxes
        IndexesPanelSubject  matlab.ui.container.Panel
        IndexesSubject       matlab.ui.control.Table
        TVGTab               matlab.ui.container.Tab
        GraphicPanelTVG      matlab.ui.container.Panel
        GraphicTVG           matlab.ui.control.UIAxes
        IndexesPanelTVG      matlab.ui.container.Panel
        IndexesTVG           matlab.ui.control.Table
        NetworkPanelTVG      matlab.ui.container.Panel
        SpinnerTVG           matlab.ui.control.Spinner
        SliderTVG            matlab.ui.control.Slider
        NetworkTVG           matlab.ui.control.UIAxes
        REATab               matlab.ui.container.Tab
        NetworkPanelREA      matlab.ui.container.Panel
        SpinnerREA           matlab.ui.control.Spinner
        SliderREA            matlab.ui.control.Slider
        NetworkREA           matlab.ui.control.UIAxes
        GraphicPanelREA      matlab.ui.container.Panel
        GraphicREA           matlab.ui.control.UIAxes
        IndexesPanelREA      matlab.ui.container.Panel
        IndexesREA           matlab.ui.control.Table
        SubjectsPanel        matlab.ui.container.Panel
        Subjects             matlab.ui.control.ListBox
        PropertiesPanel      matlab.ui.container.Panel
        TaoLabel             matlab.ui.control.Label
        TaoMax_Value         matlab.ui.control.Label
        TimeLabel            matlab.ui.control.Label
        Time_Value           matlab.ui.control.Label
        NumNodesLabel        matlab.ui.control.Label
        NumNodes_Value       matlab.ui.control.Label
        LagWindowLabel       matlab.ui.control.Label
        LagWindow_Value      matlab.ui.control.Label
        ThresholdLabel       matlab.ui.control.Label
        Threshold_Value      matlab.ui.control.Label
        WinLen_Value         matlab.ui.control.Label
        WindowLengthLabel    matlab.ui.control.Label
    end


    properties (Access = public)

    end

    properties (Access = private)
        files
        project
        actualSubject

        GroupIndex
        SubjectIndex
        TVGIndex
        REAIndex

        TVGNetworkValue
        REANetworkValue

    end

    methods (Access = private)

        function update(app)
            app.updateGroup();
            app.updateSubject();
            app.updateTVG();
            app.updateREA();
        end

        function updateGroup(app)
            % Update the data in the IndexesGroup table
            app.IndexesGroup.Data = app.project.Group.getMeasuresData({'name','average'});

            % Update the graphical representation of the group data
            app.updateGraphicGroup();
        end


        function updateGraphicGroup(app)
            % Update the graphic for the current group measure
            currentMeasure = app.project.Group.Measures(app.GroupIndex);

            % Plot a histogram of the measure's values
            histogram(app.GraphicGroup, currentMeasure.value);

            % Set a title for the plot
            title(app.GraphicGroup, currentMeasure.name, 'Color', [1, 1, 1]);
        end

        function updateSubject(app)
            % Update the data in the IndexesSubject table
            app.IndexesSubject.Data = app.actualSubject.TVG.getMeasuresData({'name','average', 'standardDeviation', 'variationCoefficient'});

            % Update the graphical representation of the subject data
            app.updateGraphicSubject();

        end

        function updateGraphicSubject(app)
            % Update the graphic for the current subject measure
            currentMeasure = app.actualSubject.TVG.Measures(app.SubjectIndex);

            % Plot a histogram of the measure's frame averages
            histogram(app.GraphicSubject, currentMeasure.frameAverage);

            % Set a title for the plot
            title(app.GraphicSubject, currentMeasure.name, 'Color', [1, 1, 1]);
        end

        function updateTVG(app)
            tvgLength = length(app.actualSubject.TVG.Data);

            app.SliderTVG.Limits = [0 tvgLength];
            app.SliderTVG.MajorTicks  = [0 tvgLength];

            % Set major ticks and labels for SliderTVG
            app.SliderTVG.MajorTicks = [0 tvgLength];
            app.SliderTVG.MajorTickLabels = {'0', num2str(tvgLength)};

            % Get the current TVGValue
            TVGValue = app.TVGNetworkValue;

            app.SliderTVG.Value = TVGValue;
            app.SpinnerTVG.Value = app.SliderTVG.Value;
            app.updateNetworkTVG(TVGValue);
        end

        function updateNetworkTVG(app, value)
            currentMeasure = app.actualSubject.TVG.Measures(app.SubjectIndex);

            % Update the Data for TVG measures
            app.IndexesTVG.Data = app.actualSubject.TVG.getMeasuresData({'name', 'average'});

            % Retrieve the graph at the specified position
            g = app.actualSubject.TVG.getGraphAtPosition(value);

            % Retrieve node labels
            labels = app.actualSubject.Configuration.NodesLabels;

            % Retrieve node coordinates
            coord = getNodeLocations(labels, 'Location32.txt');
            x = coord(:, 1);
            y = coord(:, 2);

            % Plot the graph
            h = plot(app.NetworkTVG, g, 'XData', x, 'YData', y);

            % Label nodes
            serie = 1:app.actualSubject.Configuration.NumberOfNodes;
            labelnode(h, serie, labels);

            % Update the graphic
            app.updateGraphicTVG(currentMeasure, value);
        end


        function updateGraphicTVG(app, currentMeasure, value)

            interval = 50;

            % Calculate the range based on the specified interval
            minValue = max(1, value - interval);
            maxValue = min(length(currentMeasure.frameAverage), value + interval);

            % Extract the data within the range
            tvgIndexInterval = currentMeasure.frameAverage(minValue:maxValue);

            if value < interval
                mark = value;
            else
                mark = int32((maxValue - minValue)/2);
            end

            % Plot the data
            plot(app.GraphicTVG, tvgIndexInterval, '-x', 'MarkerSize', 15, 'MarkerEdgeColor', 'red', 'MarkerIndices', mark);
            title(app.GraphicTVG, [currentMeasure.name, ' Distribution'], "Color",[1,1,1]);
        end

        function updateREA(app)

            % Update the slider and spinner values based on REAValue
            REAValue = app.REANetworkValue;
            app.SliderREA.Value = REAValue;
            app.SpinnerREA.Value = REAValue;

            % Update the network or data based on REAValue
            app.updateNetworkREA(REAValue);
        end

        function updateNetworkREA(app, value)

            % Data processing
            app.IndexesREA.Data = app.actualSubject.ASN.getMeasuresData({'name','average'});
            REA = app.actualSubject.ASN.Data;
            UREA = unique(REA);
            UREA(UREA == 0) = [];
            REA(REA < UREA(value)) = 0;
            numNodes = length(REA);

            % Create a graph
            g = graph(REA);

            % Set slider limits
            app.SliderREA.Limits = [1 length(UREA)];

            % Edge weights and color mapping
            weights = g.Edges.Weight;
            NormWei = weights ./ max(weights);
            labels = app.actualSubject.Configuration.NodesLabels;
            coord = getNodeLocations(labels, 'Location32.txt');
            x = coord(:, 1);
            y = coord(:, 2);
            cm = colormap(app.NetworkREA, winter);

            % Calculate colors based on NormWei
            colorID = max(1, sum(NormWei > [0:1/length(cm(:, 1)):1], 2));
            myColor = cm(colorID, :);

            serie = 1:numNodes;

            % Adjust edge width based on NormWei
            edgeWidth = (NormWei) * 10;

            h = plot(app.NetworkREA, g, 'XData', x, 'YData', y, 'LineWidth', edgeWidth, 'EdgeColor', myColor);
            labelnode(h, serie, labels);

            % Call the updateGraphicREA function
            app.updateGraphicREA;
        end

        function updateGraphicREA(app)
            % Get the current measure from app.actualSubject.ASN
            currentMeasure = app.actualSubject.ASN.Measures(app.REAIndex);

            % Create a histogram plot using currentMeasure.value
            histogram(app.GraphicREA, currentMeasure.value);

            % Set the title of the graphic
            title(app.GraphicREA, [currentMeasure.name " Distribution"], "Color",[1,1,1]);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, project)

            % Clear XTick and YTick for NetworkREA
            app.NetworkREA.XTick = [];
            app.NetworkREA.YTick = [];

            % Set XTick for NetworkTVG
            app.NetworkTVG.XTick = [0:1:100];
            app.NetworkTVG.YTick = [];

            % Initialize app.files and set various Text properties
            app.files = project.Configuration.Files;
            app.WinLen_Value.Text = num2str(project.Configuration.SlidWindow);
            app.Threshold_Value.Text = num2str(project.Configuration.Threshold);
            app.TaoMax_Value.Text = num2str(project.Configuration.TaoMax);
            app.NumNodes_Value.Text = num2str(project.Configuration.NumberOfNodes);
            app.Time_Value.Text = [num2str(project.Configuration.InitialTime) '~' num2str(project.Configuration.FinalTime)];
            app.Subjects.Items = app.files;

            % Set project and actualSubject properties
            app.project = project;
            app.actualSubject = app.project.Group.Subjects(1);

            % Initialize various index and value properties
            app.GroupIndex = 1;
            app.SubjectIndex = 1;
            app.TVGIndex = 1;
            app.REAIndex = 1;
            app.TVGNetworkValue = 1;
            app.REANetworkValue = 1;

            % Call the update function to perform initial updates
            app.update();
        end

        % Menu selected function: SaveProjectMenu
        function SaveProjectMenuSelected(app, event)
            [file,path,indx] = uiputfile('*.mat', 'Save Project', 'project.mat');
            if indx > 0
                project = app.project;
                save(strcat(path,file),'project', '-v7.3');
            end
        end

        % Value changing function: SpinnerREA
        function AllowTimeColumnCheckBoxValueChanged(app, event)

        end

        % Value changed function: Subjects
        function SubjectsValueChanged(app, event)
            % Get the selected value from the dropdown
            value = app.Subjects.Value;

            % Find the index of the selected value in the list of files
            [~, idx] = ismember(value, app.files);

            % Set the currently selected subject in the app to the one corresponding to the selected file
            app.actualSubject = app.project.Group.Subjects(idx);

            % Update the app's interface with the new subject data
            app.update();
        end

        % Value changed function: SliderTVG
        function SliderTVGValueChanged(app, event)
            % Get the current value of the slider
            value = round(app.SliderTVG.Value);

            % Update the SpinnerTVG with the same value
            app.SpinnerTVG.Value = value;

            % Update the TVGNetworkValue in the app
            app.TVGNetworkValue = value;

            % Update the network display based on the new value
            app.updateNetworkTVG(value);
        end

        % Cell selection callback: IndexesGroup
        function IndexesGroupCellSelection(app, event)
            % Get the index of the selected cell
            app.GroupIndex = event.Indices(1);

            % Update the graphic display for the selected group index
            app.updateGraphicGroup;
        end

        % Value changed function: SpinnerTVG
        function SpinnerTVGValueChanged(app, event)
            % Get the current value of the spinner
            value = round(app.SpinnerTVG.Value);

            % Update the SliderTVG with the same value
            app.SliderTVG.Value = value;

            % Update the TVGNetworkValue in the app
            app.TVGNetworkValue = value;

            % Update the network display based on the new value
            app.updateNetworkTVG(value);
        end

        % Cell edit callback: IndexesSubject
        function IndexesSubjectCellEdit(app, event)

        end

        % Cell selection callback: IndexesSubject
        function IndexesSubjectCellSelection(app, event)
            % Get the index of the selected cell
            app.SubjectIndex = event.Indices(1);

            % Update the subject display based on the selected index
            app.updateSubject;
        end

        % Cell selection callback: IndexesTVG
        function IndexesTVGCellSelection(app, event)
            % Get the index of the selected cell
            app.TVGIndex = event.Indices(1);

            % Update the TVG network display based on the selected index
            app.updateNetworkTVG(app.TVGNetworkValue);
        end

        % Value changed function: SliderREA
        function SliderREAValueChanged(app, event)
            % Get the current value of the slider
            value = round(app.SliderREA.Value);

            % Update the SpinnerREA with the same value
            app.SpinnerREA.Value = value;

            % Update the REANetworkValue in the app
            app.REANetworkValue = value;

            % Update the REA network display based on the new value
            app.updateNetworkREA(value);
        end

        % Value changed function: SpinnerREA
        function SpinnerREAValueChanged(app, event)
            % Get the current value of the spinner
            value = round(app.SpinnerREA.Value);

            % Update the SliderREA with the same value
            app.SliderREA.Value = value;

            % Update the REANetworkValue in the app
            app.REANetworkValue = value;

            % Update the REA network display based on the new value
            app.updateNetworkREA(value);
        end

        % Cell selection callback: IndexesREA
        function IndexesREACellSelection(app, event)
            % Get the index of the selected cell
            app.REAIndex = event.Indices(1);

            % Update the REA network display based on the selected index
            app.updateNetworkREA(app.REANetworkValue);
        end

        % Size changed function: TVGTab
        function TVGTabSizeChanged(app, event)
            position = app.TVGTab.Position;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Program = uifigure('Visible', 'off');
            app.Program.Name = 'MoSyn - Motif Synchronization';
            app.FileMenu = uimenu(app.Program);
            app.FileMenu.Text = 'File';
            app.SaveProjectMenu = uimenu(app.FileMenu);
            app.SaveProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
            app.SaveProjectMenu.Separator = 'on';
            app.SaveProjectMenu.Accelerator = 's';
            app.SaveProjectMenu.Text = 'Save Project';
            app.AboutMenu = uimenu(app.Program);
            app.AboutMenu.Text = 'About';
            app.PropertiesPanel = uipanel(app.Program);
            app.PropertiesPanel.Title = 'Properties';
            app.WindowLengthLabel = uilabel(app.PropertiesPanel);
            app.WindowLengthLabel.Text = 'Window Length';
            app.WinLen_Value = uilabel(app.PropertiesPanel);
            app.WinLen_Value.Text = '0';
            app.Threshold_Value = uilabel(app.PropertiesPanel);
            app.Threshold_Value.Text = '0';
            app.ThresholdLabel = uilabel(app.PropertiesPanel);
            app.ThresholdLabel.Text = 'Threshold';
            app.LagWindow_Value = uilabel(app.PropertiesPanel);
            app.LagWindow_Value.Text = '0';
            app.LagWindowLabel = uilabel(app.PropertiesPanel);
            app.LagWindowLabel.Text = 'Lag Window';
            app.NumNodes_Value = uilabel(app.PropertiesPanel);
            app.NumNodes_Value.Text = '0';
            app.NumNodesLabel = uilabel(app.PropertiesPanel);
            app.NumNodesLabel.Text = 'Num Nodes';
            app.Time_Value = uilabel(app.PropertiesPanel);
            app.Time_Value.Text = '0~63000';
            app.TimeLabel = uilabel(app.PropertiesPanel);
            app.TimeLabel.Text = 'Time';
            app.TaoMax_Value = uilabel(app.PropertiesPanel);
            app.TaoMax_Value.Text = '0';
            app.TaoLabel = uilabel(app.PropertiesPanel);
            app.TaoLabel.Text = 'Tao';
            app.SubjectsPanel = uipanel(app.Program);
            app.SubjectsPanel.Title = 'Subjects';
            app.Subjects = uilistbox(app.SubjectsPanel);
            app.Subjects.Items = {};
            app.Subjects.ValueChangedFcn = createCallbackFcn(app, @SubjectsValueChanged, true);
            app.Subjects.Value = {};
            app.TabGroup = uitabgroup(app.Program);
            app.GroupTab = uitab(app.TabGroup);
            app.GroupTab.Title = 'Group';
            app.IndexesPanelGroup = uipanel(app.GroupTab);
            app.IndexesPanelGroup.Title = 'Indexes';
            app.IndexesGroup = uitable(app.IndexesPanelGroup);
            app.IndexesGroup.ColumnName = {'Indexes'; 'Mean'};
            app.IndexesGroup.RowName = {};
            app.IndexesGroup.CellSelectionCallback = createCallbackFcn(app, @IndexesGroupCellSelection, true);
            app.GraphicPanelGroup = uipanel(app.GroupTab);
            app.GraphicPanelGroup.Title = 'Graphic';
            app.GraphicGroup = uiaxes(app.GraphicPanelGroup);
            title(app.GraphicGroup, 'Degree')
            app.SubjectTab = uitab(app.TabGroup);
            app.SubjectTab.Title = 'Subject';
            app.IndexesPanelSubject = uipanel(app.SubjectTab);
            app.IndexesPanelSubject.Title = 'Indexes';
            app.IndexesSubject = uitable(app.IndexesPanelSubject);
            app.IndexesSubject.ColumnName = {'Indexes'; 'Mean'; 'STD'; 'CV'};
            app.IndexesSubject.RowName = {};
            app.IndexesSubject.ColumnEditable = true;
            app.IndexesSubject.CellEditCallback = createCallbackFcn(app, @IndexesSubjectCellEdit, true);
            app.IndexesSubject.CellSelectionCallback = createCallbackFcn(app, @IndexesSubjectCellSelection, true);
            app.GraphicPanelSubject = uipanel(app.SubjectTab);
            app.GraphicPanelSubject.Title = 'Graphic';
            app.GraphicSubject = uiaxes(app.GraphicPanelSubject);
            title(app.GraphicSubject, 'Degree')
            app.TVGTab = uitab(app.TabGroup);
            app.TVGTab.SizeChangedFcn = createCallbackFcn(app, @TVGTabSizeChanged, true);
            app.TVGTab.Title = 'TVG';
            app.NetworkPanelTVG = uipanel(app.TVGTab);
            app.NetworkPanelTVG.Title = 'Network';
            app.NetworkTVG = uiaxes(app.NetworkPanelTVG);
            app.SliderTVG = uislider(app.NetworkPanelTVG);
            app.SliderTVG.Limits = [0 60000];
            app.SliderTVG.MajorTickLabels = {'0', '1.2e+04', '2.4e+04', '3.6e+04', '4.8e+04', '6e+04'};
            app.SliderTVG.ValueChangedFcn = createCallbackFcn(app, @SliderTVGValueChanged, true);
            app.SliderTVG.MinorTicks = [];
            app.SpinnerTVG = uispinner(app.NetworkPanelTVG);
            app.SpinnerTVG.Step = 0.5;
            app.SpinnerTVG.Limits = [0 Inf];
            app.SpinnerTVG.ValueDisplayFormat = '%.0f';
            app.SpinnerTVG.ValueChangedFcn = createCallbackFcn(app, @SpinnerTVGValueChanged, true);
            app.IndexesPanelTVG = uipanel(app.TVGTab);
            app.IndexesPanelTVG.Title = 'Indexes';
            app.IndexesTVG = uitable(app.IndexesPanelTVG);
            app.IndexesTVG.ColumnName = {'Indexes'; 'Values'};
            app.IndexesTVG.RowName = {};
            app.IndexesTVG.CellSelectionCallback = createCallbackFcn(app, @IndexesTVGCellSelection, true);
            app.GraphicPanelTVG = uipanel(app.TVGTab);
            app.GraphicPanelTVG.Title = 'Graphic';
            app.GraphicTVG = uiaxes(app.GraphicPanelTVG);
            title(app.GraphicTVG, 'Degree')
            app.GraphicTVG.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.REATab = uitab(app.TabGroup);
            app.REATab.Title = 'REA';
            app.IndexesPanelREA = uipanel(app.REATab);
            app.IndexesPanelREA.Title = 'Indexes';
            app.IndexesREA = uitable(app.IndexesPanelREA);
            app.IndexesREA.ColumnName = {'Indexes'; 'Values'};
            app.IndexesREA.RowName = {};
            app.IndexesREA.CellSelectionCallback = createCallbackFcn(app, @IndexesREACellSelection, true);
            app.GraphicPanelREA = uipanel(app.REATab);
            app.GraphicPanelREA.Title = 'Graphic';
            app.GraphicREA = uiaxes(app.GraphicPanelREA);
            app.NetworkPanelREA = uipanel(app.REATab);
            app.NetworkPanelREA.Title = 'Network';
            app.NetworkREA = uiaxes(app.NetworkPanelREA);
            app.SliderREA = uislider(app.NetworkPanelREA);
            app.SliderREA.Limits = [1 60000];
            app.SliderREA.MajorTicks = [];
            app.SliderREA.MajorTickLabels = {};
            app.SliderREA.ValueChangedFcn = createCallbackFcn(app, @SliderREAValueChanged, true);
            app.SliderREA.MinorTicks = [];
            app.SliderREA.Value = 1;
            app.SpinnerREA = uispinner(app.NetworkPanelREA);
            app.SpinnerREA.ValueChangingFcn = createCallbackFcn(app, @AllowTimeColumnCheckBoxValueChanged, true);
            app.SpinnerREA.Limits = [0 Inf];
            app.SpinnerREA.ValueDisplayFormat = '%.0f';
            app.SpinnerREA.ValueChangedFcn = createCallbackFcn(app, @SpinnerREAValueChanged, true);
            % Show the figure after all components are created
            app.Program.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UISync(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Program)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Program)
        end
    end
end